classdef Wireal_1_6_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uiFigureMain            matlab.ui.Figure
        menuFile                matlab.ui.container.Menu
        menuImdel               matlab.ui.container.Menu
        menuView                matlab.ui.container.Menu
        menuHelp                matlab.ui.container.Menu
        menuGuide               matlab.ui.container.Menu
        menuAbout               matlab.ui.container.Menu
        axesMain                matlab.ui.control.UIAxes
        axesRes                 matlab.ui.control.UIAxes
        axesHeart               matlab.ui.control.UIAxes
        lstBoxState             matlab.ui.control.ListBox
        panelNetwork            matlab.ui.container.Panel
        textAddr                matlab.ui.control.Label
        editIP                  matlab.ui.control.EditField
        textPort                matlab.ui.control.Label
        editPort                matlab.ui.control.EditField
        panelWorkspace          matlab.ui.container.Panel
        textDir                 matlab.ui.control.Label
        btnSelectDir            matlab.ui.control.Button
        panelData               matlab.ui.container.Panel
        textNtx                 matlab.ui.control.Label
        editNtx                 matlab.ui.control.EditField
        textNrx                 matlab.ui.control.Label
        editNrx                 matlab.ui.control.EditField
        textHz                  matlab.ui.control.Label
        editHz                  matlab.ui.control.EditField
        panelCtrl               matlab.ui.container.Panel
        textDetectStreamIndex   matlab.ui.control.Label
        btnNextDetectStream     matlab.ui.control.Button
        labelConnect            matlab.ui.control.Label
        labelHz                 matlab.ui.control.Label
        labelMIMO               matlab.ui.control.Label
        dispConnect             matlab.ui.control.EditField
        dispHz                  matlab.ui.control.EditField
        dispMIMO                matlab.ui.control.EditField
        textBreathRateTag       matlab.ui.control.Label
        textResRateValue        matlab.ui.control.Label
        textHeartbeatRateTag    matlab.ui.control.Label
        textHeartbeatRateValue  matlab.ui.control.Label
        textResStatics          matlab.ui.control.Label
        textHeartbeatStatics    matlab.ui.control.Label
        panelPlotMode           matlab.ui.container.Panel
        textStreamIndex         matlab.ui.control.Label
        btnNextStream           matlab.ui.control.Button
        panelSubcarrierOptions  matlab.ui.container.Panel
        editSubcarrierIndex     matlab.ui.control.EditField
        textSubcarrierLeader    matlab.ui.control.Label
        dropDownSubcarrier      matlab.ui.control.DropDown
        text8                   matlab.ui.control.Label
        editMaxSec              matlab.ui.control.EditField
        text9                   matlab.ui.control.Label
        btnStart                matlab.ui.control.Button
        btnContinue             matlab.ui.control.Button
        btnPause                matlab.ui.control.Button
        checkboxTemp            matlab.ui.control.CheckBox
        btnCut                  matlab.ui.control.Button
        text25                  matlab.ui.control.Label
        editAxisLow             matlab.ui.control.EditField
        text26                  matlab.ui.control.Label
        editAxisHigh            matlab.ui.control.EditField
        textTempWarn            matlab.ui.control.Label
        checkboxVitalDetect     matlab.ui.control.CheckBox
        labelRunningTime        matlab.ui.control.Label
        textRunningTime         matlab.ui.control.Label
        PlotModeSwitchLabel     matlab.ui.control.Label
        PlotModeSwitch          matlab.ui.control.Switch
    end

    
    properties (Access = private)
        mainTimer                     % system timer object
        havntBeenStarted              % to indicate whether it has been started
        filename
        fileHandle                    % file handle of the data file
        curPos                        % Current position of file read pointer.
        paused
        pausedPlotData                % saved plotData when system is paused.
        pausedPlotX                   % saved plotX when system is paused.
        pausedRealNum
        haveSelectedDir
        workingDir
        adapterName
        Ntx
        Nrx
        waitedPackNum                 % how many packs have come (mainly for debug use)
        everyPackSec
        dataFre                       % data frequency
        ip                            % listen ip address
        server_ip                     % ip of server(this machine).
        port
        plotMaxSec                    % how many packs (in secs) will be plotted
        plotGapNum
        plotData
        plotMode                      % plot mode, 's' means split, 'c' means combine
        splitIndex
        detectIndex
        subcarrierIndex
        axisLow
        axisHigh
        time_begin2draw               % the time when data begin to draw
    end
    
    properties (Access = public)
        havBeenCut                    % have been cut times
        lastCutNoFile        
        dateFormat
        blankFormat 
        lstBoxCacheThreshold          % controls how often to clear listbox       
    end
    
    methods (Access = private)
        function mainTimerStartFcn(app, ~, ~)
            
            if (app.havntBeenStarted == true)
                tStr = datestr(now, 30);
                timestamp = [tStr(1 : 4), '-', tStr(5 : 6), '-', tStr(7 : 8), '--', tStr(10 : 11), '-', tStr(12 : 13), '-', tStr(14 : 15)];
                workingDir = app.workingDir;
                address = app.ip;
                port = app.port;
                filename = fullfile(workingDir, timestamp);
                tcp_recv('csi_tcp_recv', address, port, filename);
                app.filename = filename;
                app.havntBeenStarted = false;
                
                updateLstBoxState(app, 'Ready for collecting data...');
            end
        end
        
        function mainTimerRunningFcn(app, ~, ~)
            
            filename = app.filename;
            if exist(filename, 'file') == 0
                return;
            end
            
            % When program runs at this point that means data have come
            % We can roughly say this moment is the beginning of running time
            % Be careful to initialize begin2draw time only once (preset defalt value
            % -1)
            
            if app.time_begin2draw == -1
                app.time_begin2draw = 0;
                tic;
                %myHandles.Info.cputime_begin2draw = cputime;
            end
            
            fhand = app.fileHandle;
            % -1 represents the default value of fileHandle
            if fhand == -1
                fhand = fopen(filename, 'rb');
                % After excuting fopen, errors will come when fhand is less than 0.
                if (fhand < 0)
                    fprintf('Couldn''t open file %s', filename);
                    %toc;
                    return;
                end
                app.fileHandle = fhand;
            end
            
            Ntx = app.Ntx;
            Nrx = app.Nrx;
            
            % More details in read_bfee.c
            % sizeof(csi) = floor((30 * (Ntx * Nrx * 8 * 2 + 3) + 7) / 8)
            % sizeof(other contents) = 20 (eg. from inBytes[0] to inBytes[19], 20 bytes in total)
            % 3 = 2 (2 bytes size) + 1 (1 byte code)
            
            thresholdSize = floor((30 * (Ntx * Nrx * 8 * 2 + 3) + 7) / 8) + 20 + 3;
            
            %Something wrong with dir(filename).bytes, may it is unsafe for IO.
            %dirInfo = dir(filename);
            %fileSize = dirInfo.bytes;
            
            fseek(fhand, 0, 'eof');
            fileSize = ftell(fhand);
            cur = app.curPos;
            fseek(fhand, cur, 'bof');
            recvSize = fileSize - cur;
            if (recvSize < thresholdSize) && (app.paused == false)
                clearLstBoxStateCache(app);
                updateLstBoxState(app, sprintf('No more than %d bytes!', thresholdSize));
                app.textRunningTime.Text = sec2dhms(toc);
                %myHandles.textRunningTime.String = sec2dhms(cputime - myHandles.Info.cputime_begin2draw);
                %toc;
                return;
            end
            [pack, cur] = read_bf_file_real_time(fhand, cur, recvSize, Ntx, Nrx);
            
            % Attention this function will slow down the frame and make system lose real-time property.
            %validateCorrectness(pack);
            
            % Update curPos
            app.curPos = cur;
            % If no valid data retrieved, pack will be set to cell(1, 2)
            if size(pack, 2) == 2
                if app.paused == false
                    clearLstBoxStateCache(app);
                    updateLstBoxState(app, sprintf('Received %d bytes but no valid contents...', recvSize));
                end
                app.textRunningTime.Text = sec2dhms(toc);
                %myHandles.textRunningTime.String = sec2dhms(cputime - myHandles.Info.cputime_begin2draw);
                %toc;
                return;
            end
            % Extract CSI from raw data matrix.
            array = adjust_CSI(pack, Ntx, Nrx, 30);
            if strcmp(app.dropDownSubcarrier.Value, '1')
                array = getAverageCSI(array, 30);
            else
                array = getSubcarrierCSI(array, 30, app.subcarrierIndex);
            end
            
            % temporary variable, remember to update original value
            plotData = app.plotData;
            plotGapNum = app.plotGapNum;
            plotMaxPack = size(plotData, 2);
            packNum = size(array, 2);
            if (plotGapNum == 0)
                plotData = [plotData(:, packNum + 1 : end), array];
            elseif (packNum <= plotGapNum)
                currentGapPos = plotMaxPack - plotGapNum + 1;
                plotData(:, currentGapPos : currentGapPos + packNum - 1) = array;
                plotGapNum = plotGapNum - packNum;
            elseif (packNum >= plotMaxPack)
                plotData = array(packNum - plotMaxPack + 1 : end);
                plotGapNum = 0;
            else
                plotData = [plotData(:, packNum - plotGapNum + 1 : end - plotGapNum), array];
                plotGapNum = 0;
            end
            
            % update original value
            app.plotData = plotData;
            app.plotGapNum = plotGapNum;
            realNum = plotMaxPack - plotGapNum;
            %realNum = plotMaxPack;
            
            if (plotGapNum == 0)
                plotX = (1 : plotMaxPack) * app.everyPackSec;
            else
                plotX = (plotMaxPack - realNum + 1 : plotMaxPack) * app.everyPackSec;
            end
            
            % Set function is better since you still obtain focus.
            %set(gcf, 'CurrentAxes', myHandles.axes1);
            %myHandles.figure1.CurrentAxes = myHandles.axes1;
            %axes(myHandles.axes1);
            % axis([0, myHandles.Info.plotMaxSec, myHandles.Info.axisLow, myHandles.Info.axisHigh]);
            % If is in the pause state, only draw the pause time figure(why draw? for control).
            if (app.paused == true)
                if isempty(app.pausedPlotData)
                    app.pausedPlotData = plotData;
                    app.pausedPlotX = plotX;
                    app.pausedRealNum = realNum;
                else
                    plotData = app.pausedPlotData;
                    plotX = app.pausedPlotX;
                    realNum = app.pausedRealNum;
                end
            end
            cla(app.axesMain);
            hold(app.axesMain, 'on');
            if (app.plotMode == 'c')
                tag = strings(1, Ntx * Nrx);
                for i = 1 : size(plotData, 1)
                    % Camera from right(oldest) to left(newest):
                    %plot(plotX, plotData(i, realNum : -1 : 1));
                    plot(app.axesMain, plotX, plotData(i, 1 : realNum));
                    tag(:, i) = sprintf('Spatial Stream  %d', i);
                end
                legend(app.axesMain, tag, 'Location', 'SouthEast');
                %drawnow update;
            % else plotMode == 's'
            else
                splitIndex = app.splitIndex;
                % Camera from right(oldest) to left(newest):
                %plot(plotX, plotData(splitIndex, realNum : -1 : 1), 'r');
                plot(app.axesMain, plotX, plotData(splitIndex, 1 : realNum), 'r');
                legend(app.axesMain, sprintf('Spatial Stream  %d', splitIndex), 'Location', 'SouthEast');
            end
            hold(app.axesMain, 'off');
            
            % For pause state control we can't use plotGapNum to determine if data is
            % full of the axis, cause at pause state plotGapNum belongs to real time
            % data
            %if (plotGapNum == 0) && get(myHandles.checkboxVitalDetect, 'Value')
            
            if get(app.checkboxVitalDetect, 'Value') && (plotMaxPack == realNum)
                
                detectIndex = app.detectIndex;
                intered_data = interpolation_data(plotData);
                frequency = app.dataFre;
                
                %approximated_sec_pass = packNum / frequency;
                %shift_sec = myHandles.Info.shift_sec + approximated_sec_pass;
                % Respiration
                
                res_data = butterFilter_realtime(intered_data, frequency, 0);
                res_rate = getVitalRate(res_data, frequency, 0);
                
                app.textResStatics.Visible = 'on';
                app.textResStatics.Text = rate2StaticsStr(res_rate);
                %selected_res_rate = removeMaxAndMinThenMean(res_rate);
                selected_res_rate = res_rate(1, detectIndex);
                
                app.textResRateValue.Text = sprintf('%d', selected_res_rate);
                app.textResRateValue.FontColor = 'red';
                
                cla(app.axesRes);
                hold(app.axesRes, 'on');
                
                % Camera from right to left:
                %plot(plotX, res_data(detectIndex, realNum : -1 : 1), 'y');
                
                % Camera from left to right:
                plot(app.axesRes, plotX, res_data(detectIndex, 1 : realNum), 'y');
                hold(app.axesRes, 'off');
                
                % Heartbeat
                heart_data = butterFilter_realtime(intered_data, frequency, 1);
                heart_rate = getVitalRate(heart_data, frequency, 1);
                
                app.textHeartbeatStatics.Text = rate2StaticsStr(heart_rate);
                app.textHeartbeatStatics.Visible = 'on';
                %selected_heart_rate = removeMaxAndMinThenMean(heart_rate);
                selected_heart_rate = heart_rate(1, detectIndex);
                
                app.textHeartbeatRateValue.Text = sprintf('%d', selected_heart_rate);
                app.textHeartbeatRateValue.FontColor = 'red';
                
                cla(app.axesHeart);
                hold(app.axesHeart, 'on');
                
                % Camera from right to left:
                %plot(plotX, heart_data(detectIndex, realNum : -1 : 1), 'g');
                
                % Camera from left to right:
                plot(app.axesHeart, plotX, heart_data(detectIndex, 1 : realNum), 'g');
                hold(app.axesHeart, 'off');
                %myHandles.Info.shift_sec = shift_sec;
            end
            if app.paused == false
            	clearLstBoxStateCache(app);
                updateLstBoxState(app, sprintf('Received %d bytes...', recvSize));
            end
            %
            %pause(0.000001);% drawnow;
            % drawnow update;
            app.textRunningTime.Text = sec2dhms(toc);
            %myHandles.textRunningTime.String = sec2dhms(cputime - myHandles.Info.cputime_begin2draw);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function uiFigureMainStartupFcn(app)
            
            % maximize           
            useWindowAPI2Maximize(app.uiFigureMain);
            
            % For Application Logo(top left).
%             imshow('logo.jpg', app.uiFigureMain);

            % init common properties
            app.havntBeenStarted = true; 
            app.havBeenCut = 0;
            app.lastCutNoFile = false;
            app.filename = 'test';
            app.fileHandle = -1;
            app.curPos = 0;    
            app.paused = false;
            app.pausedPlotData = [];  
            app.pausedPlotX = [];  
            app.pausedRealNum = -1;
            app.dateFormat = 'mm/dd HH:MM:SS:FFF';
            app.blankFormat = blanks(8);
            app.haveSelectedDir = false;
            app.workingDir = matlabroot;
            app.adapterName = 'Ethernet';
            app.Ntx = str2double(app.editNtx.Value);
            app.Nrx = str2double(app.editNrx.Value);
            app.waitedPackNum = 0;
            app.everyPackSec = 1 / str2double(app.editHz.Value);
            app.dataFre = str2double(app.editHz.Value);
            app.ip = app.editIP.Value;
            app.server_ip = '192.168.8.88';
            app.port = app.editPort.Value;
            app.plotMaxSec = str2double(app.editMaxSec.Value);
            app.plotGapNum = 0;
            app.plotData = [];
            app.plotMode = 'c';
            app.splitIndex = str2double(app.textStreamIndex.Text);
            app.detectIndex = str2double(app.textDetectStreamIndex.Text);
            app.subcarrierIndex = str2double(app.editSubcarrierIndex.Value);
            app.axisLow = str2double(app.editAxisLow.Value);
            app.axisHigh = str2double(app.editAxisHigh.Value);
            app.time_begin2draw = -1;
            app.lstBoxCacheThreshold = 500;
        
            % init timer object
            app.mainTimer = timer(...
                'Period', 0.05, ...
                'StartDelay', 0, ...
                'TasksToExecute', inf, ...
                'ExecutionMode', 'fixedSpacing', ...
                'StartFcn', @app.mainTimerStartFcn, ...
                'TimerFcn', @app.mainTimerRunningFcn);
            
            axis(app.axesMain, [0, app.plotMaxSec, app.axisLow, app.axisHigh]);
            
            xlim(app.axesRes, [0, app.plotMaxSec]);            
            xticks(app.axesRes, 'auto');
            yticks(app.axesRes, 'auto');
            
            xlim(app.axesHeart, [0, app.plotMaxSec]);
            xticks(app.axesHeart, 'auto');
            yticks(app.axesHeart, 'auto');
        end

        % Button pushed function: btnContinue
        function btnContinue_Callback(app, event)

            app.btnPause.Visible = 'on';
%           %set(hObject, 'Visible', 'off');
            app.btnContinue.Visible = 'off';
            %set(app.btnNextStream, 'Enable', 'on');
            app.paused = false;
            app.pausedPlotData = [];
            app.pausedPlotX = [];
            app.pausedRealNum = -1;
            
            updateLstBoxState(app, 'Continue...');
            %start(app.timer);
        end

        % Button pushed function: btnCut
        function btnCut_Callback(app, event)

            % shutdown tcp connection
            tcp_recv('csi_tcp_recv');
            
            % close file
            fclose('all');
            
            % stop timer
            stop(app.mainTimer);
            
            % clear axes
            cla(app.axesMain);
            cla(app.axesRes);
            cla(app.axesHeart);
            
            clearLstBoxStateCache(app, 'cut');
                               
            havBeenCutTimes = app.havBeenCut;
%             if havBeenCutTimes == 0
%                 app.lstBoxState.Value = {};
%                 app.lstBoxState.Items = {};
%             else
%                 if app.lastCutNoFile == false
%                     lastFileRecord = cell2mat(app.lstBoxState.Items(havBeenCutTimes));
%                     lastFileRecord = cat(2, lastFileRecord, '   <HISTORY>');
%                     app.lstBoxState.Items(havBeenCutTimes) = {lastFileRecord};
%                 end
%                 app.lstBoxState.Items = app.lstBoxState.Items(1:havBeenCutTimes);
%                 app.lstBoxState.Value = app.lstBoxState.Items(end);
%             end
            
            filename = app.filename;
            if exist(filename, 'file')
                app.lastCutNoFile = false;
                if app.checkboxTemp.Value
                    system(['del ', filename]);
                    updateLstBoxState(app, ['"', filename, '"', '  has been cut and deleted.']);
                else
                    updateLstBoxState(app, ['"', filename, '"', '  has been cut and saved.']);
                end
                app.havBeenCut = havBeenCutTimes + 1;
            else
                app.lastCutNoFile = true;
                updateLstBoxState(app, ['"', filename, '"', '  doesn''''t exist.']);
            end
            
            app.havntBeenStarted = true;
            app.plotGapNum = app.plotMaxSec * app.dataFre;
            app.curPos = 0;
            app.fileHandle = -1;
            app.time_begin2draw = -1;
            app.paused = false;
            app.pausedPlotData = [];
            app.pausedPlotX = [];
            app.pausedRealNum = -1;
            
            %app.Info.shift_sec = 0;
            
%             set(hObject, 'Visible', 'off');
            app.btnCut.Visible = 'off';
            app.btnPause.Visible = 'off';
            app.btnContinue.Visible = 'off';
            %set(app.btnNextStream, 'Enable', 'on');
            app.labelConnect.Visible = 'off';
            app.labelHz.Visible = 'off';
            app.labelMIMO.Visible = 'off';
            app.labelRunningTime.Visible = 'off';
            app.textRunningTime.Visible = 'off';
            app.dispConnect.Visible = 'off';
            app.dispHz.Visible = 'off';
            app.dispMIMO.Visible = 'off';
            
            app.btnStart.Visible = 'on';
            app.editSubcarrierIndex.Enable = 'on';
            app.dropDownSubcarrier.Enable = 'on';
            app.btnSelectDir.Enable = 'on';
            app.editPort.Enable = 'on';
            app.editIP.Enable = 'on';
            app.editHz.Enable = 'on';
            app.editNtx.Enable = 'on';
            app.editNrx.Enable = 'on';
            app.editMaxSec.Enable = 'on';
            app.checkboxVitalDetect.Enable = 'on';
            
            if app.checkboxVitalDetect.Value
                app.textResRateValue.Text = 'Ready';
                app.textHeartbeatRateValue.Text = 'Ready';
                app.textResRateValue.FontColor = [0, 0.8, 0.2];
                app.textHeartbeatRateValue.FontColor = [0, 0.8, 0.2];
            else
                app.textResRateValue.Text = 'Suspend';
                app.textHeartbeatRateValue.Text = 'Suspend';
                app.textResRateValue.FontColor = 'black';
                app.textHeartbeatRateValue.FontColor = 'black';
            end
            
            app.textResStatics.Visible = 'off';
            app.textResStatics.Text = '';
            app.textHeartbeatStatics.Visible = 'off';
            app.textHeartbeatStatics.Text = '';
            app.textRunningTime.Text = '0 d  0 h  0 m  0 s';
        end

        % Button pushed function: btnNextDetectStream
        function btnNextDetectStream_Callback(app, event)
           
            detectIndex = app.detectIndex;
            if (detectIndex == app.Ntx * app.Nrx)
                app.detectIndex = 1;
            else
                app.detectIndex = detectIndex + 1;
            end
            app.textDetectStreamIndex.Text = sprintf('%d', app.detectIndex);
        end

        % Button pushed function: btnNextStream
        function btnNextStream_Callback(app, event)
            
            splitIndex = app.splitIndex;
            if (splitIndex == app.Ntx * app.Nrx)
                app.splitIndex = 1;
            else
                app.splitIndex = splitIndex + 1;
            end
            app.textStreamIndex.Text = sprintf('%d', app.splitIndex);
        end

        % Button pushed function: btnPause
        function btnPause_Callback(app, event)
            
            app.btnContinue.Visible = 'on';
%           %set(hObject, 'Visible', 'off');
            app.btnPause.Visible = 'off';
            %set(app.btnNextStream, 'Enable', 'off');
            app.paused = true;
            updateLstBoxState(app, 'Paused...');
            %stop(app.timer);
            %disp('stoped');
        end

        % Button pushed function: btnSelectDir
        function btnSelectDir_Callback(app, event)

            if app.haveSelectedDir == false
                server_ip = app.server_ip;
                adapterName = app.adapterName;
                
                [admin_check_status, ~] = system('net session >nul 2>&1');
                if admin_check_status ~= 0
                    %warndlg('Please run as administrator!', 'WARNING');
                    %errordlg('Please run as administrator.', 'Permission Error');
                    uialert(app.uiFigureMain, 'Please run as administrator.', 'Permission Error');
                    return;
                end
                
                % Attention, here is findstr"onnected", will match "disconnected" and
                % "Connected".
                %[check_adapter_name_status, ~] = system(sprintf('netsh interface show interface name="%s" | findstr "onnected"', adapterName));
                
                [check_adapter_name_status, ~] = system(sprintf('chcp 437 && netsh interface show interface name="%s" | findstr "onnected"', adapterName));
                if check_adapter_name_status ~= 0
                    %warndlg(sprintf('Please rename your LAN adapter to "%s"', adapterName), 'WARNING');
                    %errordlg(sprintf('Please rename your LAN adapter to "%s".', adapterName), 'Adapter Missed Error');
                    uialert(app.uiFigureMain, sprintf('Please rename your LAN adapter to "%s".', adapterName), 'Adapter Missed Error');
                    return;
                end
            end
            
            %if (strcmp(get(app.timer, 'Running'), 'on'))
            %    warndlg('Cannot select working directory while detecting!', 'WARNING');
            %    return;
            %end
            
            workDir = uigetdir(matlabroot, 'Select working directory');
            if (workDir == 0)
                return;
            end
            
            app.textDir.Text = workDir;
            if app.haveSelectedDir == false
                while true
                    
                    %[~, check_connected_result] = system(sprintf('netsh interface show interface name="%s" | findstr "Connected"', adapterName));
                    [check_connected_status, ~] = system(sprintf('chcp 437 && netsh interface show interface name="%s" | findstr "Connected"', adapterName));
                    if check_connected_status ~= 0
                   
                        %btn = questdlg(sprintf('Adapter "%s" is disconnected, do you want to continue to run in offline simulation mode?', adapterName), 'Continue or Retry', 'OK', 'Retry', 'Retry');
                        btn = uiconfirm(app.uiFigureMain, sprintf('Adapter "%s" is disconnected, do you want to continue to run in offline simulation mode?', adapterName), 'Continue or Retry', 'Options', {'OK', 'Retry'}, 'DefaultOption', 2);                        
                        switch btn
                            case 'OK'
                                updateLstBoxState(app, 'Run in offline simulation mode successfully...');
                                app.panelNetwork.Visible = 'off';
                                break;
                            case 'Retry'
                                continue;
                        end
                    else
                        system([sprintf('netsh int ip set address "%s" static ', adapterName), server_ip]);
                        updateLstBoxState(app, ['IPv4 address has been set to ', server_ip, ' successfully...']);
                        break;
                    end
                end
            end
            %app.Info.filename = workDir;
            app.haveSelectedDir = true;
            app.workingDir = workDir;
        end

        % Button pushed function: btnStart
        function btnStart_Callback(app, event)

            if (app.haveSelectedDir == false)
                %warndlg('Haven''t selected working directory.', 'Warning');
                uialert(app.uiFigureMain, 'Please select working directory.', 'Invalid Working Directory', 'Icon', 'warning'); 
                return;
            end
            
            app.labelConnect.Visible = 'on';
            app.labelHz.Visible = 'on';
            app.labelMIMO.Visible = 'on';
            app.labelRunningTime.Visible = 'on';
            app.textRunningTime.Visible = 'on';
            app.dispConnect.Visible = 'on';
            app.dispHz.Visible = 'on';
            app.dispMIMO.Visible = 'on';
            
            plotMaxSec = app.plotMaxSec;
            dataFre = app.dataFre;
            streamCnt = app.Ntx * app.Nrx;
            app.everyPackSec = 1 / dataFre;
            app.plotGapNum = plotMaxSec * dataFre;
            app.plotData = zeros(streamCnt, app.plotGapNum);
            
            updateLstBoxState(app, 'Waited socket connection...');
            
            app.dispConnect.Value = sprintf('%s : %s', app.ip, app.port);
            app.dispHz.Value = sprintf('%d', app.dataFre);
            app.dispMIMO.Value = sprintf('Ntx = %d   Nrx = %d', app.Ntx, app.Nrx);
            
            % update UI immediately
            %pause(0.000001);
%             set(hObject, 'Visible', 'off');
            app.btnStart.Visible = 'off';
            app.editSubcarrierIndex.Enable = 'off';
            app.dropDownSubcarrier.Enable = 'off';
            app.btnSelectDir.Enable = 'off';
            app.btnPause.Visible = 'on';
            app.btnCut.Visible = 'on';
            app.editHz.Enable = 'off';
            app.editPort.Enable = 'off';
            app.editIP.Enable = 'off';
            app.editMaxSec.Enable = 'off';
            app.editNtx.Enable = 'off';
            app.editNrx.Enable = 'off';
            app.checkboxVitalDetect.Enable = 'off';
            start(app.mainTimer);
        end

        % Value changed function: checkboxTemp
        function checkboxTemp_Callback(app, event)
            
            if app.checkboxTemp.Value
                app.textTempWarn.Visible = 'on';
            else
                app.textTempWarn.Visible = 'off';
            end
        end

        % Value changed function: checkboxVitalDetect
        function checkboxVitalDetect_Callback(app, event)

            if app.checkboxVitalDetect.Value
                app.textResRateValue.Text = 'Ready';
                %app.textResRateValue.ForegroundColor = 'g';
                app.textResRateValue.FontColor = [0, 0.8, 0.2];
                app.textHeartbeatRateValue.Text = 'Ready';
                %app.textHeartbeatRateValue.ForegroundColor = 'g';
                app.textHeartbeatRateValue.FontColor = [0, 0.8, 0.2];
                app.textDetectStreamIndex.Visible = 'on';
                app.btnNextDetectStream.Visible = 'on';
                %set(app.textResRateValue, 'Visible', 'on');
                %set(app.textHeartbeatRateValue, 'Visible', 'on');
            else
                app.textResRateValue.Text = 'Suspend';
                app.textResRateValue.FontColor = 'black';
                app.textHeartbeatRateValue.Text = 'Suspend';
                app.textHeartbeatRateValue.FontColor = 'black';
                app.textDetectStreamIndex.Visible = 'off';
                app.btnNextDetectStream.Visible = 'off';
                %set(app.textResRateValue, 'Visible', 'off');
                %set(app.textHeartbeatRateValue, 'Visible', 'off');
            end
        end

        % Value changed function: editAxisHigh
        function editAxisHigh_Callback(app, event)
            
            editAxisHigh = app.editAxisHigh.Value;
            res = str2double(editAxisHigh);
            axisLow = app.axisLow;
            if (isnan(res) || res <= 0 || res > 999999999 || res <= axisLow)
                %warndlg('Invalid Axis Upper Bound : Must be a positive decimal and greater than the lower bound of axis', 'WARNING');
                %errordlg('Invalid Axis Upper Bound : Must be a positive decimal or integer and greater than the lower bound of axis and less than 999999999.', 'Input Error');
                uialert(app.uiFigureMain, 'Invalid Axis Upper Bound : Must be a positive decimal or integer and greater than the lower bound of axis and less than 999999999.', 'Input Error');
                app.editAxisHigh.Value = sprintf('%d', app.axisHigh);
                res = app.axisHigh;
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                if rem(res, 1) == 0
                    app.editAxisHigh.Value = sprintf('%d', res);
                end
                app.axisHigh = res;
            end
            ylim(app.axesMain, [axisLow res]);
            yticks(app.axesMain, 'auto');
        end

        % Value changed function: editAxisLow
        function editAxisLow_Callback(app, event)
           
            editAxisLow = app.editAxisLow.Value;
            res = str2double(editAxisLow);
            axisHigh = app.axisHigh;
            if (isnan(res) || res < 0 || res >= axisHigh || imag(res))
                %warndlg('Invalid Axis Lower Bound : Must be a decimal above or equal 0 and less than the upper bound of axis', 'WARNING');
            	%errordlg('Invalid Axis Lower Bound : Must be a decimal or an integer above or equal 0 and less than the upper bound of axis.', 'Input Error', 'nonmodal');
             	uialert(app.uiFigureMain, 'Invalid Axis Lower Bound : Must be a decimal or an integer above or equal 0 and less than the upper bound of axis.', 'Input Error');               
                app.editAxisLow.Value = sprintf('%d', app.axisLow);
                res = app.axisLow;
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                if rem(res, 1) == 0
                    app.editAxisLow.Value = sprintf('%d', res);
                end
                app.axisLow = res;
            end
            ylim(app.axesMain, [res axisHigh]);
            yticks(app.axesMain, 'auto');
        end

        % Value changed function: editHz
        function editHz_Callback(app, event)
            
            textHz = app.editHz.Value;
            res = str2double(textHz);
            if (isnan(res) || res < 1 || res > 999999999 || rem(res, 1) ~= 0)
                %warndlg('Invalid data frequency : Must be an integer above 0', 'WARNING');
                %errordlg('Invalid data frequency : Must be an integer between 1 to 999999999.', 'Input Error');
                uialert(app.uiFigureMain, 'Invalid data frequency : Must be an integer between 1 to 999999999.', 'Input Error');
                app.editHz.Value = sprintf('%d', app.dataFre);
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.editHz.Value = sprintf('%d', res);
                app.dataFre = res;
            end
        end

        % Value changed function: editIP
        function editIP_Callback(app, event)

            %        str2double(get(hObject,'String')) returns contents of editIP as a double           
            textIP = app.editIP.Value;
            app.ip = textIP;
        end

        % Value changed function: editMaxSec
        function editMaxSec_Callback(app, event)
            
            lenTxt = app.editMaxSec.Value;
            res = str2double(lenTxt);
            if (isnan(res) || res < 1 || res > 999999999 || rem(res, 1) ~= 0)
                %warndlg('Max Length Invalid: Must be an integer above 0!', 'WARNING');
                %errordlg('Max Length Invalid : Must be an integer between 1 to 999999999.', 'Input Error');
                uialert(app.uiFigureMain, 'Max Length Invalid : Must be an integer between 1 to 999999999.', 'Input Error');
                formerMaxSec = app.plotMaxSec;
                app.editMaxSec.Value = sprintf('%d', formerMaxSec);
                res = formerMaxSec;
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.editMaxSec.Value = sprintf('%d', res);
                app.plotMaxSec = res;
            end
            xlim(app.axesMain, [0 res]);
            xticks(app.axesMain, 'auto');
            xlim(app.axesRes, [0 res]);
            xticks(app.axesRes, 'auto');
            xlim(app.axesHeart, [0 res]);
            xticks(app.axesHeart, 'auto');
        end

        % Value changed function: editNrx
        function editNrx_Callback(app, event)
            
            textNrx = app.editNrx.Value;
            res = str2double(textNrx);
            if (isnan(res) || res < 1 || res > 999999999 || rem(res, 1) ~= 0)
                %warndlg('Invalid Nrx number : Must be an integer above 0', 'WARNING');
                %errordlg('Invalid Nrx number : Must be an integer between 1 to 999999999.', 'Input Error');
                uialert(app.uiFigureMain, 'Invalid Nrx number : Must be an integer between 1 to 999999999.', 'Input Error');
                app.editNrx.Value = sprintf('%d', app.Nrx);
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.editNrx.Value = sprintf('%d', res);
                app.Nrx = res;
                % Initialize necessary value to make sure 'roll' button works correctly.
                app.splitIndex = 1;
                app.detectIndex = 1;
                app.textStreamIndex.Text = '1';
                app.textDetectStreamIndex.Text = '1';
            end
        end

        % Value changed function: editNtx
        function editNtx_Callback(app, event)
            
            textNtx = app.editNtx.Value;
            res = str2double(textNtx);
            if (isnan(res) || res < 1 || res> 999999999 || rem(res, 1) ~= 0)
                %warndlg('Invalid Ntx number : Must be an integer above 0', 'WARNING');
                %errordlg('Invalid Ntx number : Must be an integer between 1 to 999999999.', 'Input Error');
                uialert(app.uiFigureMain, 'Invalid Ntx number : Must be an integer between 1 to 999999999.', 'Input Error');
                app.editNtx.Value = sprintf('%d', app.Ntx);
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.editNtx.Value = sprintf('%d', res);
                app.Ntx = res;
                % Initialize necessary value to make sure 'roll' button works correctly.
                app.splitIndex = 1;
                app.detectIndex = 1;
                app.textStreamIndex.Text = '1';
                app.textDetectStreamIndex.Text = '1';
            end
        end

        % Value changed function: editPort
        function editPort_Callback(app, event)
            
            textPort = app.editPort.Value;
            res = str2double(textPort);
            if (isnan(res) || res < 1 || res > 65535 || rem(res, 1) ~= 0)
                %warndlg('Invalid Port : Must be an integer between 1 to 65535', 'WARNING');
                %errordlg('Invalid Port : Must be an integer between 1 to 65535.', 'Input Error');
                uialert(app.uiFigureMain, 'Invalid Port : Must be an integer between 1 to 65535.', 'Input Error');
                app.editPort.Value = app.port;
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.editPort.Value = sprintf('%d', res);
                app.port = app.editPort.Value;
            end
        end

        % Value changed function: editSubcarrierIndex
        function editSubcarrierIndex_Callback(app, event)
            
            subcarrierIndex = app.editSubcarrierIndex.Value;
            res = str2double(subcarrierIndex);
            if (isnan(res) || res < 1 || res > 30 || rem(res, 1) ~= 0)
                %warndlg('Invalid subcarrier index : Must be an integer between 1 to 30', 'WARNING');
                %errordlg('Invalid subcarrier index : Must be an integer between 1 to 30.', 'Input Error');
                uialert(app.uiFigureMain, 'Invalid subcarrier index : Must be an integer between 1 to 30.', 'Input Error');
                app.editSubcarrierIndex.Value = sprintf('%d', app.subcarrierIndex);
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.editSubcarrierIndex.Value = sprintf('%d', res);
                app.subcarrierIndex = res;
            end
        end

        % Close request function: uiFigureMain
        function uiFigureMainCloseRequestFcn(app, event)
            
            stop(app.mainTimer);
            %btn = questdlg('Are you sure you want to exit?', 'Exit or Not', 'OK', 'Cancel', 'Cancel');
            btn = uiconfirm(app.uiFigureMain, 'Close Wireal?', 'Confirm Close', 'Icon', 'warning');
            switch btn
                case 'OK'
                    % Close tcp receive service
                    % delete(app.mainTimer);
                    delete(timerfindall);
                    fclose('all');
                    if strcmp(app.btnStart.Visible, 'off')
                        tcp_recv('csi_tcp_recv');
                        if app.checkboxTemp.Value
                            system(['del ', app.filename]);
                        end
                    end
                    
                    % If running in online mode...
                    adapterName = app.adapterName;
                    if strcmp(app.panelNetwork.Visible, 'on') && app.haveSelectedDir
                    	while (true)
                            %[~, check_connected_result] = system(sprintf('netsh interface show interface name="%s" | findstr "Connected"', adapterName));
                            [check_connected_status, ~] = system(sprintf('chcp 437 && netsh interface show interface name="%s" | findstr "Connected"', adapterName));
                            if check_connected_status ~= 0
                                %questdlg(sprintf('Adapter "%s" is disconnected, you need to plug in the cable to recover DHCP service.', adapterName), 'Adapter Unpluged', 'Retry', 'Retry');
                                selection = uiconfirm(app.uiFigureMain, sprintf('Adapter "%s" is disconnected, you need to plug in the cable to recover DHCP service.', adapterName), 'Adapter Unpluged', 'Options', {'Retry'});
                                continue;
                            else
                            	system(sprintf('netsh int ip set address "%s" dhcp', adapterName));
                                updateLstBoxState(app, 'IPv4 address has been set to DHCP successfully...');
                                pause(1);
                                break;
                            end
                        end
                    end
                    
                    delete(app);
                    
                case 'Cancel'
                    if strcmp(app.btnStart.Visible, 'off')
                        start(app.mainTimer);
                    end
                    %return;
            end
        end

        % Menu selected function: menuImdel
        function menuImdel_Callback(app, event)
            
            [~, ~] = uigetfile({'*.mat;','MATLAB Files(*.mat)';'*.*', ...
                'AllFiles(*.*)'},'Select an model');
        end

        % Value changed function: dropDownSubcarrier
        function dropDownSubcarrier_Callback(app, event)
            
            if strcmp(app.dropDownSubcarrier.Value, '2')
                app.editSubcarrierIndex.Visible = 'on';
                app.textSubcarrierLeader.Visible = 'on';
            else
                app.editSubcarrierIndex.Visible = 'off';
                app.textSubcarrierLeader.Visible = 'off';
            end
        end

        % Value changed function: PlotModeSwitch
        function PlotModeSwitchValueChanged(app, event)

            if strcmp(app.PlotModeSwitch.Value, '1')
                app.plotMode = 'c';
                app.btnNextStream.Visible = 'off';
                app.textStreamIndex.Visible = 'off';
            else
                app.plotMode = 's';
                app.btnNextStream.Visible = 'on';
                app.textStreamIndex.Visible = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uiFigureMain = uifigure;
            app.uiFigureMain.Name = 'Wireal';
            app.uiFigureMain.CloseRequestFcn = createCallbackFcn(app, @uiFigureMainCloseRequestFcn, true);
            app.menuFile = uimenu(app.uiFigureMain);
            app.menuFile.Separator = 'on';
            app.menuFile.Text = 'File';
            app.menuImdel = uimenu(app.menuFile);
            app.menuImdel.MenuSelectedFcn = createCallbackFcn(app, @menuImdel_Callback, true);
            app.menuImdel.Accelerator = 'I';
            app.menuImdel.Text = 'Import Model';
            app.menuView = uimenu(app.uiFigureMain);
            app.menuView.Text = 'View';
            app.menuHelp = uimenu(app.uiFigureMain);
            app.menuHelp.Text = 'Help';
            app.menuGuide = uimenu(app.menuHelp);
            app.menuGuide.Accelerator = 'G';
            app.menuGuide.Text = 'Getting Started';
            app.menuAbout = uimenu(app.menuHelp);
            app.menuAbout.Accelerator = 'A';
            app.menuAbout.Text = 'About';
            app.axesMain = uiaxes(app.uiFigureMain);
            xlabel(app.axesMain, 'Time [s]')
            ylabel(app.axesMain, 'SNR [dB]')
            app.axesMain.XLim = [0 14];
            app.axesMain.YLim = [0 30];
            app.axesRes = uiaxes(app.uiFigureMain);
            title(app.axesRes, 'Decoded Respiration Waveform')
            xlabel(app.axesRes, 'Time [s]')
            app.axesRes.XTick = [];
            app.axesHeart = uiaxes(app.uiFigureMain);
            title(app.axesHeart, 'Decoded Heartbeat Waveform')
            xlabel(app.axesHeart, 'Time [s]')
            app.axesHeart.XTick = [];
            app.lstBoxState = uilistbox(app.uiFigureMain);
            app.lstBoxState.Items = {};
            app.lstBoxState.Value = {};
            app.panelNetwork = uipanel(app.uiFigureMain);
            app.panelNetwork.Title = 'NETWORK';
            app.textAddr = uilabel(app.panelNetwork);
            app.textAddr.Text = 'Address';
            app.editIP = uieditfield(app.panelNetwork, 'text');
            app.editIP.ValueChangedFcn = createCallbackFcn(app, @editIP_Callback, true);
            app.editIP.Tooltip = {'IP address'; ''};
            app.editIP.Value = '0.0.0.0';
            app.textPort = uilabel(app.panelNetwork);
            app.textPort.Text = 'Port';
            app.editPort = uieditfield(app.panelNetwork, 'text');
            app.editPort.ValueChangedFcn = createCallbackFcn(app, @editPort_Callback, true);
            app.editPort.Value = '8888';
            app.panelWorkspace = uipanel(app.uiFigureMain);
            app.panelWorkspace.Title = 'WORKSPACE';
            app.textDir = uilabel(app.panelWorkspace);
            app.textDir.Text = '/Working Directory/';
            app.btnSelectDir = uibutton(app.panelWorkspace, 'push');
            app.btnSelectDir.ButtonPushedFcn = createCallbackFcn(app, @btnSelectDir_Callback, true);
            app.btnSelectDir.Text = 'SELECT';
            app.panelData = uipanel(app.uiFigureMain);
            app.panelData.Title = 'DATA';
            app.textNtx = uilabel(app.panelData);
            app.textNtx.Text = 'Ntx';
            app.editNtx = uieditfield(app.panelData, 'text');
            app.editNtx.ValueChangedFcn = createCallbackFcn(app, @editNtx_Callback, true);
            app.editNtx.Value = '1';
            app.textNrx = uilabel(app.panelData);
            app.textNrx.Text = 'Nrx';
            app.editNrx = uieditfield(app.panelData, 'text');
            app.editNrx.ValueChangedFcn = createCallbackFcn(app, @editNrx_Callback, true);
            app.editNrx.Value = '3';
            app.textHz = uilabel(app.panelData);
            app.textHz.Text = 'Packet Sent Rate (Hz)';
            app.editHz = uieditfield(app.panelData, 'text');
            app.editHz.ValueChangedFcn = createCallbackFcn(app, @editHz_Callback, true);
            app.editHz.Value = '500';
            app.panelCtrl = uipanel(app.uiFigureMain);
            app.panelCtrl.Title = 'CONTROL';
            app.textDetectStreamIndex = uilabel(app.panelCtrl);
            app.textDetectStreamIndex.Visible = 'off';
            app.textDetectStreamIndex.Text = '1';
            app.btnNextDetectStream = uibutton(app.panelCtrl, 'push');
            app.btnNextDetectStream.ButtonPushedFcn = createCallbackFcn(app, @btnNextDetectStream_Callback, true);
            app.btnNextDetectStream.Visible = 'off';
            app.btnNextDetectStream.Text = 'Roll';
            app.labelConnect = uilabel(app.uiFigureMain);
            app.labelConnect.Visible = 'off';
            app.labelConnect.Text = 'CONNECT:';
            app.labelHz = uilabel(app.uiFigureMain);
            app.labelHz.Visible = 'off';
            app.labelHz.Text = 'FREQUENCY:';
            app.labelMIMO = uilabel(app.uiFigureMain);
            app.labelMIMO.Visible = 'off';
            app.labelMIMO.Text = 'MIMO:';
            app.dispConnect = uieditfield(app.uiFigureMain, 'text');
            app.dispConnect.Visible = 'off';
            app.dispHz = uieditfield(app.uiFigureMain, 'text');
            app.dispHz.Visible = 'off';
            app.dispMIMO = uieditfield(app.uiFigureMain, 'text');
            app.dispMIMO.Visible = 'off';
            app.textBreathRateTag = uilabel(app.uiFigureMain);
            app.textBreathRateTag.Text = {'Respiration Rate :'; '(Breaths/Min)'};
            app.textResRateValue = uilabel(app.uiFigureMain);
            app.textResRateValue.Text = 'Suspend';
            app.textHeartbeatRateTag = uilabel(app.uiFigureMain);
            app.textHeartbeatRateTag.Text = {'Heartbeat Rate :'; '(Beats/Min)'};
            app.textHeartbeatRateValue = uilabel(app.uiFigureMain);
            app.textHeartbeatRateValue.Text = 'Suspend';
            app.textResStatics = uilabel(app.uiFigureMain);
            app.textResStatics.Visible = 'off';
            app.textResStatics.Text = 'Respiration statics';
            app.textHeartbeatStatics = uilabel(app.uiFigureMain);
            app.textHeartbeatStatics.Visible = 'off';
            app.textHeartbeatStatics.Text = 'Heartbeat statics';
            app.panelPlotMode = uipanel(app.uiFigureMain);
            app.panelPlotMode.Title = 'STREAM PLOT MODE';
            app.textStreamIndex = uilabel(app.panelPlotMode);
            app.textStreamIndex.Visible = 'off';
            app.textStreamIndex.Text = '1';
            app.btnNextStream = uibutton(app.panelPlotMode, 'push');
            app.btnNextStream.ButtonPushedFcn = createCallbackFcn(app, @btnNextStream_Callback, true);
            app.btnNextStream.Visible = 'off';
            app.btnNextStream.Text = 'Roll';
            app.panelSubcarrierOptions = uipanel(app.uiFigureMain);
            app.panelSubcarrierOptions.Title = 'SUBCARRIER OPTIONS';
            app.editSubcarrierIndex = uieditfield(app.panelSubcarrierOptions, 'text');
            app.editSubcarrierIndex.ValueChangedFcn = createCallbackFcn(app, @editSubcarrierIndex_Callback, true);
            app.editSubcarrierIndex.Visible = 'off';
            app.editSubcarrierIndex.Value = '1';
            app.textSubcarrierLeader = uilabel(app.panelSubcarrierOptions);
            app.textSubcarrierLeader.Visible = 'off';
            app.textSubcarrierLeader.Text = '>>>>';
            app.dropDownSubcarrier = uidropdown(app.panelSubcarrierOptions);
            app.dropDownSubcarrier.Items = {'Average Mode', 'Selection Mode'};
            app.dropDownSubcarrier.ItemsData = {'1', '2'};
            app.dropDownSubcarrier.ValueChangedFcn = createCallbackFcn(app, @dropDownSubcarrier_Callback, true);
            app.dropDownSubcarrier.Value = '1';
            app.text8 = uilabel(app.uiFigureMain);
            app.text8.Text = 'Max Length';
            app.editMaxSec = uieditfield(app.uiFigureMain, 'text');
            app.editMaxSec.ValueChangedFcn = createCallbackFcn(app, @editMaxSec_Callback, true);
            app.editMaxSec.Value = '20';
            app.text9 = uilabel(app.uiFigureMain);
            app.text9.Text = '(s)';
            app.btnStart = uibutton(app.uiFigureMain, 'push');
            app.btnStart.ButtonPushedFcn = createCallbackFcn(app, @btnStart_Callback, true);
            app.btnStart.Text = 'START';
            app.btnContinue = uibutton(app.uiFigureMain, 'push');
            app.btnContinue.ButtonPushedFcn = createCallbackFcn(app, @btnContinue_Callback, true);
            app.btnContinue.Visible = 'off';
            app.btnContinue.Text = 'CONTINUE';
            app.btnPause = uibutton(app.uiFigureMain, 'push');
            app.btnPause.ButtonPushedFcn = createCallbackFcn(app, @btnPause_Callback, true);
            app.btnPause.Visible = 'off';
            app.btnPause.Text = 'PAUSE';
            app.checkboxTemp = uicheckbox(app.uiFigureMain);
            app.checkboxTemp.ValueChangedFcn = createCallbackFcn(app, @checkboxTemp_Callback, true);
            app.checkboxTemp.Text = 'Temporary';
            app.btnCut = uibutton(app.uiFigureMain, 'push');
            app.btnCut.ButtonPushedFcn = createCallbackFcn(app, @btnCut_Callback, true);
            app.btnCut.Visible = 'off';
            app.btnCut.Text = 'CUT';
            app.text25 = uilabel(app.uiFigureMain);
            app.text25.Text = 'Axis Range';
            app.editAxisLow = uieditfield(app.uiFigureMain, 'text');
            app.editAxisLow.ValueChangedFcn = createCallbackFcn(app, @editAxisLow_Callback, true);
            app.editAxisLow.Value = '0';
            app.text26 = uilabel(app.uiFigureMain);
            app.text26.Text = 'to';
            app.editAxisHigh = uieditfield(app.uiFigureMain, 'text');
            app.editAxisHigh.ValueChangedFcn = createCallbackFcn(app, @editAxisHigh_Callback, true);
            app.editAxisHigh.Value = '30';
            app.textTempWarn = uilabel(app.uiFigureMain);
            app.textTempWarn.Visible = 'off';
            app.textTempWarn.Text = '(DATA WON''T BE STORED)';
            app.checkboxVitalDetect = uicheckbox(app.uiFigureMain);
            app.checkboxVitalDetect.ValueChangedFcn = createCallbackFcn(app, @checkboxVitalDetect_Callback, true);
            app.checkboxVitalDetect.Text = 'Vital Signs Detection';
            app.labelRunningTime = uilabel(app.uiFigureMain);
            app.labelRunningTime.Visible = 'off';
            app.labelRunningTime.Text = 'RUNNING TIME:';
            app.textRunningTime = uilabel(app.uiFigureMain);
            app.textRunningTime.Visible = 'off';
            app.textRunningTime.Text = '0 d  0 h  0 m  0 s';
            app.PlotModeSwitchLabel = uilabel(app.uiFigureMain);
            app.PlotModeSwitchLabel.Text = {'Plot Mode'; ''};
            app.PlotModeSwitch = uiswitch(app.uiFigureMain, 'slider');
            app.PlotModeSwitch.Items = {'Combine', 'Split'};
            app.PlotModeSwitch.ItemsData = {'1', '2'};
            app.PlotModeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotModeSwitchValueChanged, true);
            app.PlotModeSwitch.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Wireal_1_6_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uiFigureMain)

            % Execute the startup function
            runStartupFcn(app, @uiFigureMainStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uiFigureMain)
        end
    end
end